classdef app_numerico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        TemperaturainicialEditField    matlab.ui.control.NumericEditField
        TemperaturainicialEditFieldLabel  matlab.ui.control.Label
        TemperaturafijadelltimonodoEditField_3  matlab.ui.control.NumericEditField
        TemperaturafijadelltimonodoEditField_3Label  matlab.ui.control.Label
        DerivadainicialigualaceroytemperaturafinalCheckBox  matlab.ui.control.CheckBox
        TemperaturafijadelprimernodoEditField_2  matlab.ui.control.NumericEditField
        TemperaturafijadelprimernodoEditField_2Label  matlab.ui.control.Label
        TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox  matlab.ui.control.CheckBox
        TemperaturasinicialyfinalCheckBox  matlab.ui.control.CheckBox
        TemperaturafijadelltimonodoEditField  matlab.ui.control.NumericEditField
        TemperaturafijadelltimonodoEditFieldLabel  matlab.ui.control.Label
        TemperaturafijadelprimernodoEditField  matlab.ui.control.NumericEditField
        TemperaturafijadelprimernodoLabel  matlab.ui.control.Label
        ConstantedelmaterialEditField  matlab.ui.control.NumericEditField
        ConstantedelmaterialEditFieldLabel  matlab.ui.control.Label
        NmerodenodoseneltiempoEditField  matlab.ui.control.NumericEditField
        NmerodenodoseneltiempoEditFieldLabel  matlab.ui.control.Label
        NmerodenodosenelespacioEditField  matlab.ui.control.NumericEditField
        NmerodenodosenelespacioEditFieldLabel  matlab.ui.control.Label
        TiempototaldelasimulacinEditField  matlab.ui.control.NumericEditField
        TiempototaldelasimulacinEditFieldLabel  matlab.ui.control.Label
        LongituddelasuperficiededifusinEditField  matlab.ui.control.NumericEditField
        LongituddelasuperficiededifusinEditFieldLabel  matlab.ui.control.Label
        EstadoestacionarioCheckBox     matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        longitud % Longitud del disipador
        tiempo_total % Tiempo de la simulación 
        num_nodos_espacio % Número de nodos en el espacio
        num_nodos_tiempo % Número de nodos en el tiempo
        constante_material % Constante del material
        temperatura_fija_primer_nodo % Temperatura del contorno
        temperatura_ambiente % Temperatura ambiente
        temperatura_fija_ultimo_nodo % Temperatura fija del último nodo
        A % Matriz
        T_A 
        sol_A

    end

    methods (Access = private)

        function x = pivoteoParcial(app, A, b)
            [filas, columnas] = size(A);
            if filas ~= columnas
                uialert(app.UIFigure, 'La matriz A no es cuadrada', 'Error');
                return;
            end

            n = filas;
            Ab = [A, b];
            for k = 1:n-1
                [~, i_max] = max(abs(Ab(k:n, k)));
                i_max = i_max + k-1;
                if i_max ~= k
                    Ab([k, i_max], :) = Ab([i_max, k], :);
                end
            
                for i = k+1:n
                    factor = Ab(i, k)/Ab(k, k);
                    Ab(i, k:n+1) = Ab(i, k:n+1)- factor*Ab(k, k:n+1);
                end
            end
            x = zeros(n, 1);
            x(n) = Ab(n, n+1) / Ab(n, n);
            for i = n-1:-1:1
                x(i) = (Ab(i, n+1) - Ab(i, i+1:n) * x(i+1:n)) / Ab(i, i);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EstadoestacionarioCheckBox
        function EstadoestacionarioCheckBoxValueChanged(app, event)
            value = app.EstadoestacionarioCheckBox.Value;
            if value == 1
                app.TiempototaldelasimulacinEditField.Editable = 'Off';
                app.TiempototaldelasimulacinEditFieldLabel.Enable = 'Off';
                app.TemperaturainicialEditField.Editable = 'Off';
                app.TemperaturainicialEditFieldLabel.Enable = 'Off';
            else
                app.TiempototaldelasimulacinEditField.Editable = 'On';
                app.TiempototaldelasimulacinEditFieldLabel.Enable = 'On';
                app.TemperaturainicialEditField.Editable = 'On';
                app.TemperaturainicialEditFieldLabel.Enable = 'On';
            end 
        end

        % Value changed function: TiempototaldelasimulacinEditField
        function TiempototaldelasimulacinEditFieldValueChanged(app, event)
            app.tiempo_total = app.TiempototaldelasimulacinEditField.Value;                       
        end

        % Value changed function: TemperaturasinicialyfinalCheckBox
        function TemperaturasinicialyfinalCheckBoxValueChanged(app, event)
            value = app.TemperaturasinicialyfinalCheckBox.Value;

            if value == 1
                app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Value = 0;
                app.DerivadainicialigualaceroytemperaturafinalCheckBox.Value = 0;
                app.TemperaturafijadelprimernodoEditField.Editable = 'On';
                app.TemperaturafijadelltimonodoEditField.Editable = 'On';
                app.TemperaturafijadelprimernodoEditField_2.Editable = 'Off';
                app.TemperaturafijadelltimonodoEditField_3.Editable = 'Off';
            else 
                app.TemperaturafijadelprimernodoEditField.Editable = 'Off';
                app.TemperaturafijadelltimonodoEditField.Editable = 'Off';
                
            end
        end

        % Value changed function: 
        % TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox
        function TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBoxValueChanged(app, event)
            value = app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Value;
            if value == 1
                app.TemperaturasinicialyfinalCheckBox.Value = 0;
                app.DerivadainicialigualaceroytemperaturafinalCheckBox.Value = 0;
                app.TemperaturafijadelprimernodoEditField_2.Editable = 'On';
                app.TemperaturafijadelprimernodoEditField.Editable = 'Of';
                app.TemperaturafijadelltimonodoEditField.Editable = 'Of';
                app.TemperaturafijadelltimonodoEditField_3.Editable = 'Off';
            else 
                app.TemperaturafijadelprimernodoEditField_2.Editable = 'Off';
            end
        end

        % Value changed function: 
        % DerivadainicialigualaceroytemperaturafinalCheckBox
        function DerivadainicialigualaceroytemperaturafinalCheckBoxValueChanged(app, event)
            value = app.DerivadainicialigualaceroytemperaturafinalCheckBox.Value;
            
            if value == 1
                app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Value = 0;
                app.TemperaturasinicialyfinalCheckBox.Value = 0;
                app.TemperaturafijadelltimonodoEditField_3.Editable = 'On';
                app.TemperaturafijadelprimernodoEditField.Editable = 'Of';
                app.TemperaturafijadelltimonodoEditField.Editable = 'Of';
                app.TemperaturafijadelprimernodoEditField_2.Editable = 'Off';
            else 
                app.TemperaturafijadelltimonodoEditField_3.Editable = 'Off';
            end
        end

        % Value changed function: TemperaturafijadelprimernodoEditField
        function TemperaturafijadelprimernodoEditFieldValueChanged(app, event)
            app.temperatura_fija_primer_nodo = app.TemperaturafijadelprimernodoEditField.Value;          
        end

        % Value changed function: TemperaturafijadelltimonodoEditField
        function TemperaturafijadelltimonodoEditFieldValueChanged(app, event)
            app.temperatura_fija_ultimo_nodo = app.TemperaturafijadelltimonodoEditField.Value; 
        end

        % Value changed function: TemperaturafijadelprimernodoEditField_2
        function TemperaturafijadelprimernodoEditField_2ValueChanged(app, event)
            app.temperatura_fija_primer_nodo = app.TemperaturafijadelprimernodoEditField_2.Value;
        end

        % Value changed function: TemperaturafijadelltimonodoEditField_3
        function TemperaturafijadelltimonodoEditField_3ValueChanged(app, event)
            app.temperatura_fija_ultimo_nodo = app.TemperaturafijadelltimonodoEditField_3.Value;
        end

        % Value changed function: LongituddelasuperficiededifusinEditField
        function LongituddelasuperficiededifusinEditFieldValueChanged(app, event)
            app.longitud = app.LongituddelasuperficiededifusinEditField.Value;   
        end

        % Value changed function: NmerodenodosenelespacioEditField
        function NmerodenodosenelespacioEditFieldValueChanged(app, event)
            app.num_nodos_espacio = app.NmerodenodosenelespacioEditField.Value;
        end

        % Value changed function: NmerodenodoseneltiempoEditField
        function NmerodenodoseneltiempoEditFieldValueChanged(app, event)
            app.num_nodos_tiempo = app.NmerodenodoseneltiempoEditField.Value;
        end

        % Value changed function: ConstantedelmaterialEditField
        function ConstantedelmaterialEditFieldValueChanged(app, event)
            app.constante_material = app.ConstantedelmaterialEditField.Value;
        end

        % Value changed function: TemperaturainicialEditField
        function TemperaturainicialEditFieldValueChanged(app, event)
            app.temperatura_ambiente = app.TemperaturainicialEditField.Value;
            %Inicial de los otros puntos del difusor
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes, 'reset')
            d_x = app.longitud/(app.num_nodos_espacio - 1);
            if app.EstadoestacionarioCheckBox.Value == 0
                % Transitorio
                d_t = app.tiempo_total/(app.num_nodos_tiempo - 1); 
                a = d_t;
                b = -(2*d_t + d_x^2*d_t*app.constante_material^2 + d_x^2);
                d = -d_x^2;
                % Caso 1: Temperaturas inicial y final fijas
                if app.TemperaturasinicialyfinalCheckBox.Value == 1
   
                    %Creación de la matriz
                    A_1 = [zeros(1, app.num_nodos_espacio - 2); a*eye(app.num_nodos_espacio - 3, app.num_nodos_espacio - 2)];
                    A_2 = b*eye(app.num_nodos_espacio - 2);
                    A_3 = [zeros(app.num_nodos_espacio - 2, 1) a*eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 3)];
                    app.A = A_1 + A_2 + A_3;
                    
                    temp = app.temperatura_ambiente*ones(app.num_nodos_espacio - 2, 1);
                    app.T_A = [app.temperatura_fija_primer_nodo; temp; app.temperatura_fija_ultimo_nodo];
                    app.sol_A = d*app.temperatura_ambiente*ones(app.num_nodos_espacio - 2, 1);
                    app.sol_A(1) = app.sol_A(1) - a*app.temperatura_fija_primer_nodo;
                    app.sol_A(app.num_nodos_espacio - 2) = app.sol_A(app.num_nodos_espacio - 2) - a*app.temperatura_fija_ultimo_nodo;

                    plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);
                    hold(app.UIAxes, 'on');

                    for i = 2:app.num_nodos_tiempo
                        temp = app.pivoteoParcial(app.A, app.sol_A);
                        app.T_A = [app.temperatura_fija_primer_nodo; temp; app.temperatura_fija_ultimo_nodo];
                        plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);

                        app.sol_A = d*temp;
                        app.sol_A(1) = app.sol_A(1) - a*app.temperatura_fija_primer_nodo;
                        app.sol_A(app.num_nodos_espacio - 2) = app.sol_A(app.num_nodos_espacio - 2) - a*app.temperatura_fija_ultimo_nodo;
                    end
                    hold(app.UIAxes, 'off');

                % Caso 2: Temperatura inicial fija y derivada en el último nodo igual a cero
                elseif app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Value == 1
                    A_1 = [zeros(1, app.num_nodos_espacio - 1); a*eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 1)];
                    A_2 = b*eye(app.num_nodos_espacio - 1);
                    A_3 = [zeros(app.num_nodos_espacio - 1, 1) a*eye(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2)];
                    app.A = A_1 + A_2 + A_3;
                    app.A(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2) = app.A(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2) * 2;
            
                    temp = app.temperatura_ambiente*ones(app.num_nodos_espacio - 1, 1);
                    app.T_A = [app.temperatura_fija_primer_nodo; temp];
                    app.sol_A = d*app.temperatura_ambiente*ones(app.num_nodos_espacio - 1, 1);
                    app.sol_A(1) = app.sol_A(1) - a*app.temperatura_fija_primer_nodo;
            
                    plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);
                    hold(app.UIAxes, 'on');
            
                    for i = 2:app.num_nodos_tiempo
                        temp = app.pivoteoParcial(app.A, app.sol_A);
                        app.T_A = [app.temperatura_fija_primer_nodo; temp];
                        plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);
            
                        app.sol_A = d*temp;
                        app.sol_A(1) = app.sol_A(1) - a*app.temperatura_fija_primer_nodo;
                    end
                    hold(app.UIAxes, 'off');

                % Caso 3: Derivada en el nodo inicial igual a cero y temperatura fija en el último nodo
                elseif app.DerivadainicialigualaceroytemperaturafinalCheckBox.Value == 1
                    A_1 = [zeros(1, app.num_nodos_espacio - 1); a*eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 1)];
                    A_2 = b*eye(app.num_nodos_espacio - 1);
                    A_3 = [zeros(app.num_nodos_espacio - 1, 1) a*eye(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2)];
                    app.A = A_1 + A_2 + A_3;
                    app.A(1, 2) = app.A(1, 2) * 2;
            
                    temp = app.temperatura_ambiente*ones(app.num_nodos_espacio - 1, 1);
                    app.T_A = [temp; app.temperatura_fija_ultimo_nodo];
                    app.sol_A = d*app.temperatura_ambiente*ones(app.num_nodos_espacio - 1, 1);
                    app.sol_A(app.num_nodos_espacio - 1) = app.sol_A(app.num_nodos_espacio - 1) - a*app.temperatura_fija_ultimo_nodo;
            
                    plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);
                    hold(app.UIAxes, 'on');
            
                    for i = 2:app.num_nodos_tiempo
                        temp = app.pivoteoParcial(app.A, app.sol_A);
                        app.T_A = [temp; app.temperatura_fija_ultimo_nodo];
                        plot(app.UIAxes, 0:d_x:app.longitud, app.T_A, 'LineWidth', 2);
            
                        app.sol_A = d*temp;
                        app.sol_A(app.num_nodos_espacio - 1) = app.sol_A(app.num_nodos_espacio - 1) - a*app.temperatura_fija_ultimo_nodo;
                    end
                    hold(app.UIAxes, 'off');
                end   
            else 
                % Estado estable
                a = -(2 + d_x^2*app.constante_material^2);
                if app.TemperaturasinicialyfinalCheckBox.Value == 1
                    B_1 = [zeros(1, app.num_nodos_espacio - 2); eye(app.num_nodos_espacio - 3, app.num_nodos_espacio - 2)];
                    B_2 = a*eye(app.num_nodos_espacio - 2);
                    B_3 = [zeros(app.num_nodos_espacio - 2, 1) eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 3)];
                    B = B_1 + B_2 + B_3;
                  
                    sol_B = zeros(app.num_nodos_espacio - 2, 1);
                    sol_B(1) = - app.temperatura_fija_primer_nodo;
                    sol_B(app.num_nodos_espacio - 2) = - app.temperatura_fija_ultimo_nodo;

                    T_B = app.pivoteoParcial(B, sol_B);
                    T_B = [app.temperatura_fija_primer_nodo; T_B; app.temperatura_fija_ultimo_nodo];

                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, 0:d_x:app.longitud, T_B, 'LineWidth', 2);
                    hold(app.UIAxes, 'off')

                elseif app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Value == 1
                    B_1 = [zeros(1, app.num_nodos_espacio - 1); eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 1)];
                    B_2 = a*eye(app.num_nodos_espacio - 1);
                    B_3 = [zeros(app.num_nodos_espacio - 1, 1) eye(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2)];
                    B = B_1 + B_2 + B_3;
                    B(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2) = 2;
                  
                    sol_B = zeros(app.num_nodos_espacio - 1, 1);
                    sol_B(1) = - app.temperatura_fija_primer_nodo;

                    T_B = app.pivoteoParcial(B, sol_B);
                    T_B = [app.temperatura_fija_primer_nodo; T_B];
                   
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, 0:d_x:app.longitud, T_B, 'LineWidth', 2);
                    hold(app.UIAxes, 'off')

                elseif app.DerivadainicialigualaceroytemperaturafinalCheckBox.Value == 1
                    B_1 = [zeros(1, app.num_nodos_espacio - 1); eye(app.num_nodos_espacio - 2, app.num_nodos_espacio - 1)];
                    B_2 = a*eye(app.num_nodos_espacio - 1);
                    B_3 = [zeros(app.num_nodos_espacio - 1, 1) eye(app.num_nodos_espacio - 1, app.num_nodos_espacio - 2)];
                    B = B_1 + B_2 + B_3;
                    B(1, 2) = 2;
                  
                    sol_B = zeros(app.num_nodos_espacio - 1, 1);
                    sol_B(app.num_nodos_espacio - 1) = - app.temperatura_fija_ultimo_nodo;

                    T_B = app.pivoteoParcial(B, sol_B);
                    T_B = [T_B; app.temperatura_fija_ultimo_nodo];
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, 0:d_x:app.longitud, T_B, 'LineWidth', 2);
                    hold(app.UIAxes, 'off')
                end

            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {770, 770};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {510, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EstadoestacionarioCheckBox = uicheckbox(app.LeftPanel);
            app.EstadoestacionarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstadoestacionarioCheckBoxValueChanged, true);
            app.EstadoestacionarioCheckBox.Text = 'Estado estacionario';
            app.LongituddelasuperficiededifusinEditFieldLabel = uilabel(app.LeftPanel);
            app.LongituddelasuperficiededifusinEditFieldLabel.Text = 'Longitud de la superficie de difusión';
            app.LongituddelasuperficiededifusinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongituddelasuperficiededifusinEditField.ValueChangedFcn = createCallbackFcn(app, @LongituddelasuperficiededifusinEditFieldValueChanged, true);
            app.TiempototaldelasimulacinEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempototaldelasimulacinEditFieldLabel.Text = 'Tiempo total de la simulación';
            app.TiempototaldelasimulacinEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempototaldelasimulacinEditField.ValueChangedFcn = createCallbackFcn(app, @TiempototaldelasimulacinEditFieldValueChanged, true);
            app.NmerodenodosenelespacioEditFieldLabel = uilabel(app.LeftPanel);
            app.NmerodenodosenelespacioEditFieldLabel.Text = 'Número de nodos en el espacio';
            app.NmerodenodosenelespacioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NmerodenodosenelespacioEditField.Limits = [0 Inf];
            app.NmerodenodosenelespacioEditField.ValueDisplayFormat = '%.0f';
            app.NmerodenodosenelespacioEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodenodosenelespacioEditFieldValueChanged, true);
            app.NmerodenodoseneltiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.NmerodenodoseneltiempoEditFieldLabel.Text = 'Número de nodos en el tiempo';
            app.NmerodenodoseneltiempoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NmerodenodoseneltiempoEditField.Limits = [0 Inf];
            app.NmerodenodoseneltiempoEditField.ValueDisplayFormat = '%.0f';
            app.NmerodenodoseneltiempoEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodenodoseneltiempoEditFieldValueChanged, true);
            app.ConstantedelmaterialEditFieldLabel = uilabel(app.LeftPanel);
            app.ConstantedelmaterialEditFieldLabel.Text = 'Constante del material';
            app.ConstantedelmaterialEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConstantedelmaterialEditField.ValueChangedFcn = createCallbackFcn(app, @ConstantedelmaterialEditFieldValueChanged, true);
            app.TemperaturafijadelprimernodoLabel = uilabel(app.LeftPanel);
            app.TemperaturafijadelprimernodoLabel.Text = 'Temperatura fija del primer nodo';
            app.TemperaturafijadelprimernodoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturafijadelprimernodoEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturafijadelprimernodoEditFieldValueChanged, true);
            app.TemperaturafijadelltimonodoEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperaturafijadelltimonodoEditFieldLabel.Text = 'Temperatura fija del último nodo';
            app.TemperaturafijadelltimonodoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturafijadelltimonodoEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturafijadelltimonodoEditFieldValueChanged, true);
            app.TemperaturasinicialyfinalCheckBox = uicheckbox(app.LeftPanel);
            app.TemperaturasinicialyfinalCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemperaturasinicialyfinalCheckBoxValueChanged, true);
            app.TemperaturasinicialyfinalCheckBox.Text = 'Temperaturas inicial y final';
            app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox = uicheckbox(app.LeftPanel);
            app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBoxValueChanged, true);
            app.TemperaturainicialfijayderivadadelltimonodoigualaceroCheckBox.Text = 'Temperatura inicial fija y derivada del último nodo igual a cero';
            app.TemperaturafijadelprimernodoEditField_2Label = uilabel(app.LeftPanel);
            app.TemperaturafijadelprimernodoEditField_2Label.Text = 'Temperatura fija del primer nodo';
            app.TemperaturafijadelprimernodoEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturafijadelprimernodoEditField_2.ValueChangedFcn = createCallbackFcn(app, @TemperaturafijadelprimernodoEditField_2ValueChanged, true);
            app.DerivadainicialigualaceroytemperaturafinalCheckBox = uicheckbox(app.LeftPanel);
            app.DerivadainicialigualaceroytemperaturafinalCheckBox.ValueChangedFcn = createCallbackFcn(app, @DerivadainicialigualaceroytemperaturafinalCheckBoxValueChanged, true);
            app.DerivadainicialigualaceroytemperaturafinalCheckBox.Text = 'Derivada inicial igual a cero y temperatura final';
            app.TemperaturafijadelltimonodoEditField_3Label = uilabel(app.LeftPanel);
            app.TemperaturafijadelltimonodoEditField_3Label.Text = 'Temperatura fija del último nodo';
            app.TemperaturafijadelltimonodoEditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturafijadelltimonodoEditField_3.ValueChangedFcn = createCallbackFcn(app, @TemperaturafijadelltimonodoEditField_3ValueChanged, true);
            app.TemperaturainicialEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperaturainicialEditFieldLabel.Text = 'Temperatura inicial';
            app.TemperaturainicialEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperaturainicialEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturainicialEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 't')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_numerico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end